classdef fNIRSPipeLine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EasyNIRSUIFigure               matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InitializationTab              matlab.ui.container.Tab
        InitializeSubjectsLabel        matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        RawDataButton_2                matlab.ui.control.Button
        StudyFolderButton              matlab.ui.control.Button
        NoneSelectedLabel_5            matlab.ui.control.Label
        NoneSelectedLabel_7            matlab.ui.control.Label
        SelectFoldersLabel_2           matlab.ui.control.Label
        SelectDataTypeLabel_2          matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        wl1Button                      matlab.ui.control.RadioButton
        nirsButton                     matlab.ui.control.RadioButton
        SubjectInitializationTable     matlab.ui.control.Table
        PreprocessingTab               matlab.ui.container.Tab
        CheckDataQualityPanel          matlab.ui.container.Panel
        Label_7                        matlab.ui.control.Label
        CriteriatoPruneChannelsLabel   matlab.ui.control.Label
        NoiseLabel                     matlab.ui.control.Label
        SNRmax                         matlab.ui.control.NumericEditField
        dBminLabel                     matlab.ui.control.Label
        DBminBox                       matlab.ui.control.NumericEditField
        dBmaxLabel                     matlab.ui.control.Label
        DBmaxBox                       matlab.ui.control.NumericEditField
        SDDistancemmLabel              matlab.ui.control.Label
        SDminBox                       matlab.ui.control.NumericEditField
        minLabel                       matlab.ui.control.Label
        SDmaxBox                       matlab.ui.control.NumericEditField
        maxLabel                       matlab.ui.control.Label
        dBrangeBox                     matlab.ui.control.CheckBox
        AmplitudedBLabel               matlab.ui.control.Label
        sdRangebox                     matlab.ui.control.CheckBox
        SNRbox                         matlab.ui.control.CheckBox
        maxLabel_2                     matlab.ui.control.Label
        hbMatchLabel                   matlab.ui.control.Label
        hbMatchBox                     matlab.ui.control.CheckBox
        MotionArtifactCorrectionPanel  matlab.ui.container.Panel
        SmoothingSGCheckbox            matlab.ui.control.CheckBox
        SmoothingLabel                 matlab.ui.control.Label
        SDwindowsLabel                 matlab.ui.control.Label
        SDwindowField                  matlab.ui.control.NumericEditField
        Panel_3                        matlab.ui.container.Panel
        SubjectSelectionListBox_3      matlab.ui.control.ListBox
        RunPreprocessingButton         matlab.ui.control.StateButton
        SelectAllSubjectsCheckBox_3    matlab.ui.control.CheckBox
        DataVisualizationPanel         matlab.ui.container.Panel
        CardiacFig                     matlab.ui.control.CheckBox
        SigQualityFig                  matlab.ui.control.CheckBox
        CorrectedFig                   matlab.ui.control.CheckBox
        CardiacFrequencyLabel          matlab.ui.control.Label
        SignalQualityLabel             matlab.ui.control.Label
        CorrectedSignalLabel           matlab.ui.control.Label
        DataQualitySummary             matlab.ui.control.CheckBox
        DataQualitySummaryLabel        matlab.ui.control.Label
        SignalAdjustmentPanel          matlab.ui.container.Panel
        DetrendBox                     matlab.ui.control.CheckBox
        DetrendLabel                   matlab.ui.control.Label
        Downsample                     matlab.ui.control.CheckBox
        NormalizeLabel                 matlab.ui.control.Label
        NormalizeBox                   matlab.ui.control.CheckBox
        DownsampleHzLabel              matlab.ui.control.Label
        SamplerateEditFieldLabel       matlab.ui.control.Label
        SamplerateEditField            matlab.ui.control.NumericEditField
        PreprocessingConfigurationLabel  matlab.ui.control.Label
        RunPreprocessingLabel          matlab.ui.control.Label
        PostprocessingTab              matlab.ui.container.Tab
        FZBandofinterestPanel          matlab.ui.container.Panel
        LowerBoundLabel                matlab.ui.control.Label
        UpperBoundLabel                matlab.ui.control.Label
        LB                             matlab.ui.control.NumericEditField
        UB                             matlab.ui.control.NumericEditField
        ChannelSelectionPanel          matlab.ui.container.Panel
        LaunchSDGuiButton              matlab.ui.control.StateButton
        Region1ListBox                 matlab.ui.control.ListBox
        Region2ListBox                 matlab.ui.control.ListBox
        EditField                      matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        HemoglobinParametersPanel_2    matlab.ui.container.Panel
        CheckallhemosignalsegHbOHbTHbLabel_2  matlab.ui.control.Label
        HbObox_2                       matlab.ui.control.CheckBox
        Hbbox_2                        matlab.ui.control.CheckBox
        OxyhemoglobinHbOLabel_2        matlab.ui.control.Label
        DeoxyhemoglobinHbLabel_2       matlab.ui.control.Label
        TotalhemoglobinTHbLabel_2      matlab.ui.control.Label
        OxygenSaturationStO2Label_2    matlab.ui.control.Label
        THbbox_2                       matlab.ui.control.CheckBox
        StO2box_2                      matlab.ui.control.CheckBox
        Panel_4                        matlab.ui.container.Panel
        SubjectSelectionListBox_4      matlab.ui.control.ListBox
        RunPostprocessingButton        matlab.ui.control.StateButton
        SelectAllSubjectsCheckBox_4    matlab.ui.control.CheckBox
        Label_8                        matlab.ui.control.Label
        PostprocessingConfigurationLabel  matlab.ui.control.Label
        RunPostprocessingLabel         matlab.ui.control.Label
        FigureSelectionPanel_3         matlab.ui.container.Panel
        CheckBox                       matlab.ui.control.CheckBox
        ChannelWiseStaticCoherencePlotLabel  matlab.ui.control.Label
        WaveletCheckbox                matlab.ui.control.CheckBox
        ChannelWiseWavelettransformLabel  matlab.ui.control.Label
        AnalysisTab                    matlab.ui.container.Tab
        FigureSelectionPanel_2         matlab.ui.container.Panel
        TimeVaryingWaveletTransformPowerLabel  matlab.ui.control.Label
        TimevaryingCoherenceLabel      matlab.ui.control.Label
        FrequencyVaryingCoherenceByTaskLabel  matlab.ui.control.Label
        tvwaveletfigure                matlab.ui.control.CheckBox
        tvcohfigure                    matlab.ui.control.CheckBox
        fvcohfigure                    matlab.ui.control.CheckBox
        TimeVaryingPhaseLabel          matlab.ui.control.Label
        tvphasefigure                  matlab.ui.control.CheckBox
        AnalysisConfigurationLabel     matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        RunAnalysisButton_2            matlab.ui.control.StateButton
        SubjectSelectionListBox_5      matlab.ui.control.ListBox
        SelectAllSubjectsCheckBox_5    matlab.ui.control.CheckBox
        SubjectswhohavebeenpostproccessedwillappearhereLabel  matlab.ui.control.Label
        RunAnalysisLabel               matlab.ui.control.Label
        RunAllTab                      matlab.ui.container.Tab
        RunAllLabel                    matlab.ui.control.Label
        SubjectSelectionListBox        matlab.ui.control.ListBox
        SelectAllSubjectsCheckBox_6    matlab.ui.control.CheckBox
        RunAllButton                   matlab.ui.control.Button
        SubjectswhohavebeeninitializedwillappearhereLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        postUI % Description 
        UI % Description
    end
    
    properties (Access = public)
        ChannelGUI % Description
        Data % Description
    end

    methods (Access = private)
    
        function [goodState] = testUIStateForPreprocessing(app)
        %%%
        %This function gathers information about what fields/boxes where selected
        %from the preprocessing screen and initializes the app.Data.ui
        %struct.
        %%%
            goodState = true;
            
            %% check data quality box 
        
            app.UI.Preprocessing.dbRangeBox = app.dBrangeBox.Value;
            app.UI.Preprocessing.sdRangebox = app.sdRangebox.Value;
            app.UI.Preprocessing.SNRbox = app.SNRbox.Value;
            app.UI.Preprocessing.hbMatchbox = app.hbMatchBox.Value;
            
            app.UI.Preprocessing.dBmin = app.DBminBox.Value;
            app.UI.Preprocessing.dBmax = app.DBmaxBox.Value;
            
            app.UI.Preprocessing.SDmin = app.SDminBox.Value;
            app.UI.Preprocessing.SDmax = app.SDmaxBox.Value;
            
            app.UI.Preprocessing.SNRmax = app.SNRmax.Value;


            if isequal([app.UI.Preprocessing.dbRangeBox, app.UI.Preprocessing.sdRangebox, app.UI.Preprocessing.SNRbox, ...
                    app.UI.Preprocessing.hbMatchbox], [0,0,0,0])
                warndlg('Please select at least one criterea to prune channels.')
                goodState = false;
                return 
            end
            
            %% Data Visualization box
            app.UI.Preprocessing.Figs.DataQuality = app.DataQualitySummary.Value;
            app.UI.Preprocessing.Figs.cardiacFreq = app.CardiacFig.Value;
            app.UI.Preprocessing.Figs.sigQuality = app.SigQualityFig.Value;
            app.UI.Preprocessing.Figs.corrSig = app.CorrectedFig.Value;
            
            %%Make sure a data type is Selected
            if ~(isfield(app.UI.Preprocessing, 'wl1') || isfield(app.UI.Preprocessing, 'nirs'))
                app.UI.Preprocessing.wl1 = 0;
                app.UI.Preprocessing.nirs = 1;
            end
            
            %%Subjects 
            if isfield(app.Data, 'RunningAll')
                if ~app.Data.RunningAll
                    SOI = sort(app.SubjectSelectionListBox_3.Value);
            
                    %%Make sure at least one SOI is selected
                    if isempty(SOI)
                        warndlg('Please select at least one subject for processing.','Missing Data')
                        goodState = false;
                        return 
                    else
                        app.Data.SOI = SOI;
                    end
                    
                end
            else
                
                SOI = sort(app.SubjectSelectionListBox_3.Value);
            
                %%Make sure at least one SOI is selected
                if isempty(SOI)
                    warndlg('Please select at least one subject for processing.','Missing Data')
                    goodState = false;
                    return 
                else
                    app.Data.SOI = SOI;
                end
                
            end


            app.UI.Preprocessing.SDseconds = app.SDwindowField.Value;
            app.UI.Preprocessing.SGfilter = app.SmoothingSGCheckbox.Value;
            
            %%Data processing
            app.UI.Preprocessing.TFs = app.SamplerateEditField.Value;
            app.UI.Preprocessing.Downsample = app.Downsample.Value;
            app.UI.Preprocessing.Detrend = app.DetrendBox.Value;
            app.UI.Preprocessing.Normalize = app.NormalizeBox.Value;
           
        end
       
        function [goodState] = testUIStateForPostprocessing(app)
        
            goodState = true;
            
            app.UI.Postprocessing.FZband = [app.LB.Value app.UB.Value];
            
            app.UI.Postprocessing.Fig = app.CheckBox.Value;
            app.UI.Postprocessing.WaveletFig = app.WaveletCheckbox.Value;
            
            Hemo = {};
        
            if app.HbObox_2.Value
                Hemo{end + 1} = 'HbO';
            end
            if app.Hbbox_2.Value
                Hemo{end + 1} = 'Hb'; 
            end
            if app.THbbox_2.Value
                Hemo{end + 1} = 'THb';
            end
            
            if app.StO2box_2.Value
                Hemo{end + 1} = 'StO2';
            end
            
            if isempty(Hemo)
                warndlg('Select at least one hemoglobin parameter for postprocessing.')
                goodState = false;
                return;
            end
            
            app.UI.Postprocessing.Hemo = Hemo;
            
            %%Subjects 
            if isfield(app.Data, 'RunningAll')
                if ~app.Data.RunningAll
                    SOI = sort(app.SubjectSelectionListBox_4.Value);
            
                    %%Make sure at least one SOI is selected
                    if isempty(SOI)
                        warndlg('Please select at least one subject for processing.','Missing Data')
                        goodState = false;
                        return 
                    else
                        app.Data.SOI = SOI;
                    end
                    
                end
            else
                
                SOI = sort(app.SubjectSelectionListBox_4.Value);
            
                %%Make sure at least one SOI is selected
                if isempty(SOI)
                    warndlg('Please select at least one subject for processing.','Missing Data')
                    goodState = false;
                    return 
                else
                    app.Data.SOI = SOI;
                end
                
            end


            %Check channels info
            if isfield(app.Data, 'RegionalData')
                
                app.Data.RegionalData.R1Name = app.EditField_2.Value;
                app.Data.RegionalData.R2Name = app.EditField.Value;
                
                if isfield(app.Data.RegionalData, 'R1Channels')
                   if ~isnumeric(app.Data.RegionalData.R1Channels)
                       r1Array = str2double(app.Data.RegionalData.R1Channels);
                       app.Data.RegionalData.R1Channels = r1Array;
                   end
                end
                if isfield(app.Data.RegionalData, 'R2Channels')
                   if ~isnumeric(app.Data.RegionalData.R2Channels)
                        r2Array = str2double(app.Data.RegionalData.R2Channels);
                        app.Data.RegionalData.R2Channels = r2Array;
                   end
                end
                if ~isfield(app.Data.RegionalData,'R1Intra')
                    app.Data.RegionalData.R1Intra = 0;
                end
                if ~isfield(app.Data.RegionalData,'R2Intra')
                    app.Data.RegionalData.R2Intra = 0;
                end
                if ~isfield(app.Data.RegionalData,'R1R2')
                    app.Data.RegionalData.R1R2 = 0;
                end   
            else
                warndlg('Select channels before postprocessing.')
                goodState = false;
                return;
            end
           
        end
        
        function [] = populateAnalysisSubjectList(app)
            % get initialized list
            initSubs = getInitializedSubjects(app);
            
            %get processed list
            preprocessedFolder = [app.Data.Path.study_path filesep 'Processed'];
            p = dir([preprocessedFolder filesep]);
            
            
            numberOfPre = length(p);
            
            ct = 1;
            preSubs = {};
            for i=1:length(p)
                
                if strcmp(p(i).name(1),'.')
                else
                    preSubs{ct}=p(i).name;
                    ct = ct+1;
                end
            end
            
            postSubs = {};
            ct = 1;
            for j = 1:length(preSubs)
                sub = preSubs(j);
                sub = sub{1};

                listing = dir([preprocessedFolder filesep sub]);
                
                for file = 1:length(listing)
                    nm = listing(file).name;
                    
                    if isequal(nm,'PostProcessed')
                        postSubs{ct} = sub;
                        ct = ct + 1;
                    end
                end
            end
            
            %take intersection
            common = intersect(postSubs, initSubs);
            app.SubjectSelectionListBox_5.Items = common;         
        
        end
        
        function [] = populatePostprocessingSubjectList(app)
            
    
            %get initialized list
            initSubs = getInitializedSubjects(app);
            
            %get preprocessed list
            preprocessedFolder = [app.Data.Path.study_path filesep 'Processed'];
            p = dir([preprocessedFolder filesep]);
            
            numberOfPre = length(p);
            
            ct = 1;
            preSubs = {};
            for i=1:length(p)
         
                if strcmp(p(i).name(1),'.')
                else
                    preSubs{ct}=p(i).name;
                    ct = ct+1;
                end
            end
            
            %take intersection
            common = intersect(preSubs, initSubs);
            app.SubjectSelectionListBox_4.Items = common;
            

        end
        
        function [] = populateRunAllSubjectList(app)
            initialized = getInitializedSubjects(app);
            app.SubjectSelectionListBox.Items = initialized;
        end
        
        function initSubs = getInitializedSubjects(app)
            rawDataFolder = [app.Data.Path.RawData];
            extension = '.nirs';
            listing = dir([rawDataFolder filesep '*' extension]);
            numberOfInit = length(listing);
            initSubs = {};

             for i = 1:numberOfInit
               
                varNames = who('-file', [listing(i).folder filesep listing(i).name]);
                if ismember('Snew', varNames)
                    initSubs{end + 1} = listing(i).name(1:end-length(extension));
                end
            end
        end
        
        function goodState = testUIStateForAnalysis(app)
        
            goodState = true;
        
             %%Subjects 
            if isfield(app.Data, 'RunningAll')
                if ~app.Data.RunningAll
                    SOI = sort(app.SubjectSelectionListBox_5.Value);
            
                    %%Make sure at least one SOI is selected
                    if isempty(SOI)
                        warndlg('Please select at least one subject for processing.','Missing Data')
                        goodState = false;
                        return 
                    else
                        app.Data.SOI = SOI;
                    end
                    
                end
            else
                
                SOI = sort(app.SubjectSelectionListBox_5.Value);
            
                %%Make sure at least one SOI is selected
                if isempty(SOI)
                    warndlg('Please select at least one subject for processing.','Missing Data')
                    goodState = false;
                    return 
                else
                    app.Data.SOI = SOI;
                end
                
            end
            
            app.Data.postUI.analysisFigures = [app.tvcohfigure.Value, app.tvphasefigure.Value, app.tvwaveletfigure.Value, app.fvcohfigure.Value];
            
            
        end


    end
    
    methods (Access = public)
        
        function [] = stimInitCycle(app)


           sub = (app.Data.SOI{1,1}); %can just take the SOI since there will only be one
           subfile = [app.Data.Path.RawData filesep sub '.nirs'];
           X = load(subfile, '-mat');
           TaskSelector(app, sub, X.s(:,1), X.t);
       
           populateRunAllSubjectList(app);
           
                
        end
        
         function [] = updateInitializationStimStatus(app)
            initialized = getInitializedSubjects(app);
            
            table_data = app.SubjectInitializationTable.Data;
            for i = 1:length(table_data)
                if any(strcmp(initialized, table_data{i,1}))
                   table_data{i,3} = true;
                   table_data{i,4} = 'Re-initialize';
              
               end
            end
            
            app.SubjectInitializationTable.Data = table_data;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function NIRxButtonValueChanged(app, event)
            value = app.TechEnButton_2.Value;
            app.UI.Preprocessing.NIRx = value;
            %%turn off techen
            app.TechEnButton_2.Value = ~value;
            app.UI.Preprocessing.TechEn = ~value;
            
            % based on NIRx software criteria
            app.DBminBox.Value = 110; app.UI.Preprocessing.dBmin = 110;
            app.DBmaxBox.Value = 148; app.UI.Preprocessing.dBmax = 148;
            app.SNRmax.Value = 7.5; app.UI.Preprocessing.SNRmax = 7.5;
            
      
        end

        % Callback function
        function TechEnButtonValueChanged(app, event)
        
            value = app.NIRxButton_2.Value;
            app.UI.Preprocessing.TechEn = app.TechEnButton_2.Value;
            %%turn off nirx
            
            app.NIRxButton_2.Value = ~value;
            app.UI.Preprocessing.NIRx = ~value;
            
            % based on Homer criteria
            app.DBminBox.Value = 80; app.UI.Preprocessing.dBmin = 80;
            app.DBmaxBox.Value = 125; app.UI.Preprocessing.dBMax = 125;
            app.SNRmax.Value = 5; app.UI.Preprocessing.SNRmax = 5;
        end

        % Button pushed function: StudyFolderButton
        function StudyOutputButtonPushed(app, event)
            %get study path
            Folder = cd;
            disp(cd)
            addpath(genpath(Folder));
            Folder = fullfile(Folder, ['..' filesep]);
            app.Data.Path.study_path = uigetdir(Folder,'Select the study output folder');
            
            %handle cancel event
            if isequal(app.Data.Path.study_path, 0) 
               
            else
                %set study path and update label
                cd(app.Data.Path.study_path);
                addpath(genpath(app.Data.Path.study_path));
                app.NoneSelectedLabel_5.Text = app.Data.Path.study_path;
                
                %enable other button
       
                app.RawDataButton_2.Enable = 1;
            end
        end

        % Button pushed function: RawDataButton_2
        function RawDataButtonPushed(app, event)
        
           
            Folder = cd;
            addpath(genpath(Folder));
            Folder = fullfile(Folder, ['..' filesep]);
            app.Data.Path.RawData = uigetdir(Folder,'Select the Raw Data folder');
            
            %handle cancel event
            if isequal(app.Data.Path.RawData, 0)
                return;
            end
            
                
            %clear data if there is any
            app.SubjectInitializationTable.Data = {};
            app.SubjectSelectionListBox_3.Items = {}; 
            app.Data.Demographics.Age = [];
           
           
           file_extension = '.nirs';
           listing = dir([app.Data.Path.RawData filesep '*' file_extension]); % search RawData path for .nirs files to populate subject list
           %number_of_files=length(listing);
           if isempty(listing)
               msgbox('No .nirs files in specified folder. Please ensure the correct folder is selected.')
               return;
           else
               app.NoneSelectedLabel_7.Text = app.Data.Path.RawData;
           end
           initializedSubjectList = getInitializedSubjects(app);
           table_data = {};
           Age = [];
           subject_names = {};
           
           
           for i=1:numel(listing)
               
               %Check age
               varNames = who('-file', [listing(i).folder filesep listing(i).name]);
               if ismember('age', varNames)
                   x = load([listing(i).folder filesep listing(i).name], "-mat", 'age'); %only load if its there
                   Age(end + 1) = x.age;
               else
                   Age(end + 1) = 30;
               end
               
               %Init other properties
               listing(i).name = listing(i).name(1:end-length(file_extension)); 
               subject_names{i}= listing(i).name; %Get subject name.
               table_data{i,1} = listing(i).name;
               %check if subject name{i} is in initializedSubject list, set
               %0 or 1 based on that.
               if any(strcmp(initializedSubjectList, listing(i).name))
                   table_data{i,2} = Age(i);
                   table_data{i,3} = true;
                   table_data{i,4} = 'Re-initialize';
               else
                   table_data{i,2} = Age(i); 
                   table_data{i,3} = false;
                   table_data{i,4} = 'Initialize';
               end


           end
           
           app.Data.Demographics.Age = Age;
           
           app.SubjectInitializationTable.Data = table_data;
           
           %%populate subject table
           app.SubjectSelectionListBox_3.Items = subject_names; 
           
           populatePostprocessingSubjectList(app);
           populateRunAllSubjectList(app);
           populateAnalysisSubjectList(app);
           
           
           %initialize demographics
           DemographicsProperty = {'Age'};
           T = table(DemographicsProperty,Age);
           App.Data.Demographics = T;
           %***
           
           %**Style the table
           style = uistyle("BackgroundColor", [0.96 0.18 0.33], "FontColor", 'white', "FontWeight", 'bold', "HorizontalAlignment", 'center');
           addStyle(app.SubjectInitializationTable, style, 'column', 4);
           
        end

        % Value changed function: SamplerateEditField
        function SamplerateEditFieldValueChanged(app, event)
            app.UI.Preprocessing.TFs = app.SamplerateEditField.Value;
        end

        % Value changed function: RunPreprocessingButton
        function RunPreprocessingButtonPushed(app, event)
            
            if testUIStateForPreprocessing(app)
                %preprocess data
                if app.UI.Preprocessing.wl1
                    disp('converting nirx to nirs')
                    %NIRS IS TECHEN
                    %check if nirs for selected subjects exists
                    %if not get paths of NIRX files for selected subjects
                    %send paths to homerOffflineConverter
                    disp('Please select your probe.sb/.mat file');
                    [filename, pathname] = uigetfile();
                    if(filename == 0)
                        disp('Error: No probe file selected.')
                        return;
                    end
                    
                    HomerOfflineConverter(app.Data.Path.RawData)
                  
                end
                app.RunPreprocessingButton.Text = "Running Preprocessing...";
                
         %       rehash path
                
                [app.Data] = Preprocessing(app.Data, app.UI.Preprocessing); 
                %save current data used to preprocess in PostData
                [PostData] = app.Data;
                app.RunPreprocessingButton.Text = "Run Preprocessing";
                app.postUI.preSubList = app.Data.SOI; %store current list for later reference
%                 clearvars -except PostData app
                msgbox('Preprocessing Completed'); 
                
                populatePostprocessingSubjectList(app);
                if isfield(app.Data, 'RunningAll')
                    if app.Data.RunningAll
                        app.Data.RanPreprocessing = 1;
                    end
                end
            else 
                if isfield(app.Data, 'RunningAll')
                    if app.Data.RunningAll
                        app.Data.RanPreprocessing = 0;
                    end
                end
                disp('ui was missing data for preprocessing')
                return
            end
            
        end

        % Callback function
        function TextAreaValueChanged(app, event)
%             %%converts excel format to array of numbers
%             sp = split(strip(app.TextArea.Value(1)), ','); %convert string to array of numbers by splitting at commas
%             %initialize empty list
%             app.Data.ui.AllSubs = [];
%            
%             %add each sp entry to list
%             for j = 1:length(sp)
%                 s = str2num(sp{j});
%                 for k = 1:length(s)
%                     app.Data.ui.AllSubs(end + 1) = s(k);
%                     
%                 end
%             
%             end
%             populateSubjectTable(app);   
        end

        % Callback function
        function LowfzcutoffforbpfilterHzEditFieldValueChanged(app, event)
            app.Data.ui.lf = app.LowfzcutoffforbpfilterHzEditField.Value;   
        end

        % Value changed function: RunPostprocessingButton
        function RunPostprocessingButtonValueChanged(app, event)
            disp('Post processing buttton pushed nguy')
            
%             [Data1] = load('PostData.mat'); %ADD if to check if it exists before loading it
%             [PostData] = Data1.PostData;

            if testUIStateForPostprocessing(app)
                app.RunPostprocessingButton.Text = "Running Postprocessing...";
                %choose version of postprocessing
                %Uncomment line below to run V1 
                %Postprocessing_RS2018(PostData, app.Data.ui);
                %Uncomment line below to run V2   
                PostProcessing(app.Data, app.UI.Postprocessing);
                msgbox('PostProcessing Completed');
                app.RunPostprocessingButton.Text = "Run Postprocessing";
                populateAnalysisSubjectList(app);
                
                if isfield(app.Data, 'RunningAll')
                    if app.Data.RunningAll
                        app.Data.RanPostprocessing = 1;
                    end
                end
            else 
                if isfield(app.Data, 'RunningAll')
                    if app.Data.RunningAll
                        app.Data.RanPostprocessing = 0;
                    end
                end
                disp('data was missing from UI in order to post process')
            end
          
        end

        % Value changed function: LB
        function LBValueChanged(app, event)
            app.UI.Postprocessing.FreqLB = app.LB.Value;
            
        end

        % Value changed function: UB
        function UBValueChanged(app, event)
            app.UI.Postprocessing.FreqUB = app.UB.Value;
            
        end

        % Callback function
        function HighfzcutoffforbpfilterHzEditFieldValueChanged(app, event)

            app.Data.ui.hf = app.HighfzcutoffforbpfilterHzEditField.Value;
        end

        % Value changed function: SDwindowField
        function SDwindowFieldValueChanged(app, event)
            disp('SDSecondsChanged')
            app.UI.Preprocessing.SDseconds = app.SDwindowField.Value;
            
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
        
            
            app.Data.RunningAll = 1;
            
            SOI = sort(app.SubjectSelectionListBox.Value);
            
            %%Make sure at least one SOI is selected
            if isempty(SOI)
                warndlg('Please select at least one subject for processing.','Missing Data')
                return 
            else
                app.Data.SOI = SOI;
            end
            
            %try to run preprocessing, if data is missing from UI this
            %function will end with app.Data.RanPreprocessing = 0, else if
            %preprocessing ran successfully it will end with
            %app.Data.Ranpreprocessing == 1
            RunPreprocessingButtonPushed(app, event);
            if app.Data.RanPreprocessing 
                %in which case we try to run postprocessing
                RunPostprocessingButtonValueChanged(app, event);
                if app.Data.RanPostprocessing
                    %likewise is data was missing from ui
                    %app.Data.RanPostprocessing == 0
                    RunAnalysisButtonValueChanged(app, event);
                end
            end
            
            %reset state
            app.Data.RanPreprocessing = 0; 
            app.Data.RanPostprocessing = 0;
            app.Data.RunningAll = 0;
        end

        % Value changed function: SelectAllSubjectsCheckBox_6
        function SelectAllSub_RunAll(app, event)
            if app.SelectAllSubjectsCheckBox_6.Value == 1
                app.SubjectSelectionListBox.Value = app.SubjectSelectionListBox.Items;
                app.Data.SOI = sort(app.SubjectSelectionListBox.Value);
            else 
                app.SubjectSelectionListBox.Value = {};
                app.Data.SOI = {};
            end
            
                
        end

        % Value changed function: LaunchSDGuiButton
        function LaunchSDGuiButtonValueChanged(app, event)

            app.ChannelGUI = ChannelSelectionGUI();
            app.ChannelGUI.ParentHandle = app;
        end

        % Callback function
        function SplinewSovitzkyGolayCHECKBOXValueChanged(app, event)
            app.UI.Preprocessing.SGfilter = app.SplinewSovitzkyGolayCHECKBOX.Value;
            
        end

        % Value changed function: SNRmax
        function SNRmaxValueChanged(app, event)
            app.UI.Preprocessing.SNRmax = app.SNRmax.Value;
            
        end

        % Value changed function: DBminBox
        function DBminBoxValueChanged(app, event)
            app.UI.Preprocessing.dBmin = app.DBminBox.Value;
        end

        % Value changed function: DBmaxBox
        function DBmaxBoxValueChanged(app, event)
            app.UI.Preprocessing.dBmax = app.DBminBox.Value;
        end

        % Value changed function: SDminBox
        function SDminBoxValueChanged(app, event)
            app.UI.Preprocessing.SDmin = app.SDminBox.Value;
        end

        % Value changed function: SDmaxBox
        function SDmaxBoxValueChanged(app, event)
            app.UI.Preprocessing.SDmax = app.SDmaxBox.Value;
        end

        % Value changed function: dBrangeBox
        function dBrangeBoxValueChanged(app, event)
            disp('dbRangeBoxValue changed caleld')
            value = app.dBrangeBox.Value;
            app.UI.Preprocessing.dbRangeBox = value;
            
            if value == 1 %%ensure values are in there mang
                app.UI.Preprocessing.dBmin = app.DBminBox.Value;
                app.UI.Preprocessing.dBmax = app.DBmaxBox.Value;
            end
            
            %hide/ unhide the fields
            app.dBminLabel.Enable = value;
            app.DBminBox.Enable = value;
            app.dBmaxLabel.Enable = value;
            app.DBmaxBox.Enable = value;
            
            
        end

        % Value changed function: SNRbox
        function SNRboxValueChanged(app, event)
            value = app.SNRbox.Value;
            app.UI.Preprocessing.SNRbox = value;
            
            if value == 1
                app.UI.Preprocessing.SNRmax = app.SNRmax.Value;
            end
            
            %hide/ unhide field
            app.maxLabel_2.Enable = value;
            app.SNRmax.Enable = value;
        end

        % Value changed function: hbMatchBox
        function hbMatchBoxValueChanged(app, event)
            app.UI.Preprocessing.hbMatchbox = app.hbMatchBox.Value;
        end

        % Callback function
        function SelectPreprocessedDataButtonPushed(app, event)
%             % get preprocessed files path
%             %             if exist(app.Data.Path.study_path)
%             %                 cd(app.Data.Path.study_path)
%             %             end
%             Folder = cd;
%             app.Data.Path.pre_path = uigetdir(Folder,'Select "Processed" data folder');
%             %handle cancel event
%             if isequal(app.Data.Path.pre_path, 0)
%             else
%                 %set path and update label
%                 addpath(genpath(app.Data.Path.pre_path));
%                 cd(app.Data.Path.pre_path);
%                 app.NoneSelectedLabel_4.Text = app.Data.Path.pre_path;
%             end
%             
%             listing = dir([app.Data.Path.pre_path filesep]); % search processed path for .mat files to populate subject list
%             nof=length(listing); %need to fix that it pulls two extra folder names '.' and '..''
%             disp(nof)
%             disp(listing)
%             ct = 1;
%             for i=1:numel(listing)
%                 disp('ppp')
%                 if strcmp(listing(i).name,'.') || strcmp(listing(i).name,'..') || strcmp(listing(i).name, '.DS_Store')
%                 else
%                     nameoffile{ct}=listing(i).name;
%                     ct = ct+1;
%                 end
%             end
%             
%             app.Data.AllSubs = nameoffile;
%             app.SubjectSelectionListBox.Items = nameoffile;
 
        end

        % Value changed function: RunAnalysisButton_2
        function RunAnalysisButtonValueChanged(app, event)

            if testUIStateForAnalysis(app)
                PostAnalysis_CD(app.Data)
            else
                disp('data was missing for analysis')
            end
            
                
        end

        % Callback function
        function selectall(app, event)
            value = app.SelectAllSubjectsCheckBox_2.Value;
        end

        % Value changed function: SelectAllSubjectsCheckBox_4
        function SelectAllSubjectsCheckBox_4ValueChanged(app, event)

            if app.SelectAllSubjectsCheckBox_4.Value == 1
                app.SubjectSelectionListBox_4.Value = app.SubjectSelectionListBox_4.Items;
                app.Data.SOI = sort(app.SubjectSelectionListBox_4.Value);
            else 
                app.SubjectSelectionListBox_4.Value = {};
                app.Data.SOI = {};
            end
        end

        % Value changed function: SelectAllSubjectsCheckBox_5
        function SelectAllSubjectsCheckBox_5ValueChanged(app, event)

            if app.SelectAllSubjectsCheckBox_5.Value == 1
                app.SubjectSelectionListBox_5.Value = app.SubjectSelectionListBox_5.Items;
                app.Data.SOI = sort(app.SubjectSelectionListBox_5.Value);
            else 
                app.SubjectSelectionListBox_5.Value = {};
                app.Data.SOI = {};
            end
        end

        % Value changed function: Downsample
        function DownsampleValueChanged(app, event)
            app.SamplerateEditFieldLabel.Enable = app.Downsample.Value;
            app.SamplerateEditField.Enable = app.Downsample.Value;
            
        end

        % Value changed function: sdRangebox
        function sdRangeboxValueChanged(app, event)
            value = app.sdRangebox.Value;
            app.UI.Preprocessing.sdRangebox = value;
            
            if value == 1
                app.UI.Preprocessing.SDmin = app.SDminBox.Value;
                app.UI.Preprocessing.SDmax = app.SDmaxBox.Value;
            end
            
            %hide/ unhide the fields
            app.minLabel.Enable = value;
            app.SDminBox.Enable = value;
            app.maxLabel.Enable = value;
            app.SDmaxBox.Enable = value;
            
        end

        % Value changed function: SelectAllSubjectsCheckBox_3
        function SelectAllSubjectsCheckBox_3ValueChanged(app, event)
            if app.SelectAllSubjectsCheckBox_3.Value == 1
                app.SubjectSelectionListBox_3.Value = app.SubjectSelectionListBox_3.Items;
                app.Data.SOI = sort(app.SubjectSelectionListBox_3.Value);
            else 
                app.SubjectSelectionListBox_3.Value = {};
                app.Data.SOI = {};
            end
            
        end

        % Callback function
        function SelectAllSubjectsCheckBox_2ValueChanged(app, event)
            
            if app.SelectAllSubjectsCheckBox_2.Value == 1
                app.SubjectSelectionListBox_2.Value = app.SubjectSelectionListBox_2.Items;
                app.Data.SOI = sort(app.SubjectSelectionListBox_2.Value);
            else 
                app.SubjectSelectionListBox_2.Value = {};
                app.Data.SOI = {};
            end
        end

        % Selection changed function: ButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton.Text == ".wl1" %this is the only way to identify the button...
                app.UI.Preprocessing.wl1 = 1;
                app.UI.Preprocessing.nirs = 0;
                % based on NIRx software criteria
                app.DBminBox.Value = 110; app.UI.Preprocessing.dBmin = 110;
                app.DBmaxBox.Value = 148; app.UI.Preprocessing.dBmax = 148;
                app.SNRmax.Value = 7.5; app.UI.Preprocessing.SNRmax = 7.5;
            else
                app.UI.Preprocessing.wl1 = 0;
                app.UI.Preprocessing.nirs = 1;
                % based on Homer criteria
                app.DBminBox.Value = 80; app.UI.Preprocessing.dBmin = 80;
                app.DBmaxBox.Value = 125; app.UI.Preprocessing.dBMax = 125;
                app.SNRmax.Value = 5; app.UI.Preprocessing.SNRmax = 5;
            end
    
       
        end

        % Cell selection callback: SubjectInitializationTable
        function SubjectInitializationTableCellSelection(app, event)
            indices = event.Indices;
            if (isempty(indices)); return; end 
            indices = indices(1,:); %only ever respond to the first one selected.
            action_col_index = 4;
            if (indices(2) ~= action_col_index)
                return %cell selection wasin't in the action column
            end


            %%START BUILD STIMS CODE
        
            app.Data.SOI = app.SubjectInitializationTable.Data(indices(1));
            
            app.SubjectInitializationTable.Data{indices(1),4} = 'Initializing...';
            
            stimInitCycle(app);
            
            
        end

        % Cell edit callback: SubjectInitializationTable
        function SubjectInitializationTableCellEdit(app, event)
            indices = event.Indices;
            age = event.NewData;
            sub = event.Source.Data{indices(1), 1};

            
            %update demographics: 
            app.Data.Demographics.Age(indices(1)) = age; 
            %  
            rawFile = [app.Data.Path.RawData filesep sub '.nirs'];
            
            save(rawFile, 'age', '-append')
            
        end

        % Value changed function: Region2ListBox
        function Region2ListBoxValueChanged(app, event)
            value = app.Region2ListBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EasyNIRSUIFigure = uifigure('Visible', 'off');
            app.EasyNIRSUIFigure.Name = 'Easy NIRS';
            app.TabGroup = uitabgroup(app.EasyNIRSUIFigure);
            app.InitializationTab = uitab(app.TabGroup);
            app.InitializationTab.Title = 'Initialization';
            app.InitializeSubjectsLabel = uilabel(app.InitializationTab);
            app.InitializeSubjectsLabel.Text = 'Initialize Subjects';
            app.Panel = uipanel(app.InitializationTab);
            app.RawDataButton_2 = uibutton(app.Panel, 'push');
            app.RawDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @RawDataButtonPushed, true);
            app.RawDataButton_2.Enable = 'off';
            app.RawDataButton_2.Text = 'Raw Data ';
            app.StudyFolderButton = uibutton(app.Panel, 'push');
            app.StudyFolderButton.ButtonPushedFcn = createCallbackFcn(app, @StudyOutputButtonPushed, true);
            app.StudyFolderButton.Text = 'Study Folder';
            app.NoneSelectedLabel_5 = uilabel(app.Panel);
            app.NoneSelectedLabel_5.Text = 'None Selected...';
            app.NoneSelectedLabel_7 = uilabel(app.Panel);
            app.NoneSelectedLabel_7.Text = 'None Selected...';
            app.SelectFoldersLabel_2 = uilabel(app.Panel);
            app.SelectFoldersLabel_2.Text = 'Select Folders';
            app.SelectDataTypeLabel_2 = uilabel(app.Panel);
            app.SelectDataTypeLabel_2.Text = 'Select Data Type';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.wl1Button = uiradiobutton(app.ButtonGroup);
            app.wl1Button.Text = '  .wl1';
            app.nirsButton = uiradiobutton(app.ButtonGroup);
            app.nirsButton.Text = '  .nirs';
            app.nirsButton.Value = true;
            app.SubjectInitializationTable = uitable(app.InitializationTab);
            app.SubjectInitializationTable.ColumnName = {'Subject Name'; 'Age'; 'Stims Built'; 'Action'};
            app.SubjectInitializationTable.RowName = {};
            app.SubjectInitializationTable.ColumnSortable = [true false false false];
            app.SubjectInitializationTable.ColumnEditable = [false true false false];
            app.SubjectInitializationTable.CellEditCallback = createCallbackFcn(app, @SubjectInitializationTableCellEdit, true);
            app.SubjectInitializationTable.CellSelectionCallback = createCallbackFcn(app, @SubjectInitializationTableCellSelection, true);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = '   Preprocessing';
            app.CheckDataQualityPanel = uipanel(app.PreprocessingTab);
            app.CheckDataQualityPanel.Title = 'Check Data Quality';
            app.Label_7 = uilabel(app.CheckDataQualityPanel);
            app.Label_7.Text = '';
            app.CriteriatoPruneChannelsLabel = uilabel(app.CheckDataQualityPanel);
            app.CriteriatoPruneChannelsLabel.Text = 'Criteria to Prune Channels';
            app.NoiseLabel = uilabel(app.CheckDataQualityPanel);
            app.NoiseLabel.Text = 'Noise (%)';
            app.SNRmax = uieditfield(app.CheckDataQualityPanel, 'numeric');
            app.SNRmax.Limits = [0 Inf];
            app.SNRmax.ValueChangedFcn = createCallbackFcn(app, @SNRmaxValueChanged, true);
            app.SNRmax.Value = 7.5;
            app.dBminLabel = uilabel(app.CheckDataQualityPanel);
            app.dBminLabel.Text = 'dB min';
            app.DBminBox = uieditfield(app.CheckDataQualityPanel, 'numeric');
            app.DBminBox.Limits = [0 Inf];
            app.DBminBox.ValueChangedFcn = createCallbackFcn(app, @DBminBoxValueChanged, true);
            app.DBminBox.Value = 110;
            app.dBmaxLabel = uilabel(app.CheckDataQualityPanel);
            app.dBmaxLabel.Text = 'dB max';
            app.DBmaxBox = uieditfield(app.CheckDataQualityPanel, 'numeric');
            app.DBmaxBox.Limits = [0 Inf];
            app.DBmaxBox.ValueChangedFcn = createCallbackFcn(app, @DBmaxBoxValueChanged, true);
            app.DBmaxBox.Value = 148;
            app.SDDistancemmLabel = uilabel(app.CheckDataQualityPanel);
            app.SDDistancemmLabel.Text = 'S-D Distance (mm)';
            app.SDminBox = uieditfield(app.CheckDataQualityPanel, 'numeric');
            app.SDminBox.Limits = [0 Inf];
            app.SDminBox.ValueChangedFcn = createCallbackFcn(app, @SDminBoxValueChanged, true);
            app.SDminBox.Value = 25;
            app.minLabel = uilabel(app.CheckDataQualityPanel);
            app.minLabel.Text = 'min';
            app.SDmaxBox = uieditfield(app.CheckDataQualityPanel, 'numeric');
            app.SDmaxBox.Limits = [0 Inf];
            app.SDmaxBox.ValueChangedFcn = createCallbackFcn(app, @SDmaxBoxValueChanged, true);
            app.SDmaxBox.Value = 35;
            app.maxLabel = uilabel(app.CheckDataQualityPanel);
            app.maxLabel.Text = 'max';
            app.dBrangeBox = uicheckbox(app.CheckDataQualityPanel);
            app.dBrangeBox.ValueChangedFcn = createCallbackFcn(app, @dBrangeBoxValueChanged, true);
            app.dBrangeBox.Text = '';
            app.dBrangeBox.Value = true;
            app.AmplitudedBLabel = uilabel(app.CheckDataQualityPanel);
            app.AmplitudedBLabel.Text = 'Amplitude (dB)';
            app.sdRangebox = uicheckbox(app.CheckDataQualityPanel);
            app.sdRangebox.ValueChangedFcn = createCallbackFcn(app, @sdRangeboxValueChanged, true);
            app.sdRangebox.Text = '';
            app.sdRangebox.Value = true;
            app.SNRbox = uicheckbox(app.CheckDataQualityPanel);
            app.SNRbox.ValueChangedFcn = createCallbackFcn(app, @SNRboxValueChanged, true);
            app.SNRbox.Text = '';
            app.SNRbox.Value = true;
            app.maxLabel_2 = uilabel(app.CheckDataQualityPanel);
            app.maxLabel_2.Text = 'max';
            app.hbMatchLabel = uilabel(app.CheckDataQualityPanel);
            app.hbMatchLabel.Text = 'hb Match';
            app.hbMatchBox = uicheckbox(app.CheckDataQualityPanel);
            app.hbMatchBox.ValueChangedFcn = createCallbackFcn(app, @hbMatchBoxValueChanged, true);
            app.hbMatchBox.Text = '';
            app.hbMatchBox.Value = true;
            app.MotionArtifactCorrectionPanel = uipanel(app.PreprocessingTab);
            app.MotionArtifactCorrectionPanel.Title = 'Motion Artifact Correction';
            app.SmoothingSGCheckbox = uicheckbox(app.MotionArtifactCorrectionPanel);
            app.SmoothingSGCheckbox.Text = '';
            app.SmoothingLabel = uilabel(app.MotionArtifactCorrectionPanel);
            app.SmoothingLabel.Text = 'Smoothing';
            app.SDwindowsLabel = uilabel(app.MotionArtifactCorrectionPanel);
            app.SDwindowsLabel.Text = 'SD window (s)';
            app.SDwindowField = uieditfield(app.MotionArtifactCorrectionPanel, 'numeric');
            app.SDwindowField.Limits = [0 Inf];
            app.SDwindowField.ValueChangedFcn = createCallbackFcn(app, @SDwindowFieldValueChanged, true);
            app.SDwindowField.Value = 8;
            app.Panel_3 = uipanel(app.PreprocessingTab);
            app.SubjectSelectionListBox_3 = uilistbox(app.Panel_3);
            app.SubjectSelectionListBox_3.Items = {};
            app.SubjectSelectionListBox_3.Multiselect = 'on';
            app.SubjectSelectionListBox_3.Value = {};
            app.RunPreprocessingButton = uibutton(app.Panel_3, 'state');
            app.RunPreprocessingButton.ValueChangedFcn = createCallbackFcn(app, @RunPreprocessingButtonPushed, true);
            app.RunPreprocessingButton.Text = 'Run Preprocessing';
            app.SelectAllSubjectsCheckBox_3 = uicheckbox(app.Panel_3);
            app.SelectAllSubjectsCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @SelectAllSubjectsCheckBox_3ValueChanged, true);
            app.SelectAllSubjectsCheckBox_3.Text = ' Select All Subjects';
            app.DataVisualizationPanel = uipanel(app.PreprocessingTab);
            app.DataVisualizationPanel.Title = 'Data Visualization';
            app.CardiacFig = uicheckbox(app.DataVisualizationPanel);
            app.CardiacFig.Text = '';
            app.SigQualityFig = uicheckbox(app.DataVisualizationPanel);
            app.SigQualityFig.Text = '';
            app.CorrectedFig = uicheckbox(app.DataVisualizationPanel);
            app.CorrectedFig.Text = '';
            app.CardiacFrequencyLabel = uilabel(app.DataVisualizationPanel);
            app.CardiacFrequencyLabel.Text = 'Cardiac Frequency';
            app.SignalQualityLabel = uilabel(app.DataVisualizationPanel);
            app.SignalQualityLabel.Text = 'Signal Quality';
            app.CorrectedSignalLabel = uilabel(app.DataVisualizationPanel);
            app.CorrectedSignalLabel.Text = 'Corrected Signal';
            app.DataQualitySummary = uicheckbox(app.DataVisualizationPanel);
            app.DataQualitySummary.Text = '';
            app.DataQualitySummaryLabel = uilabel(app.DataVisualizationPanel);
            app.DataQualitySummaryLabel.Text = 'Data Quality Summary';
            app.SignalAdjustmentPanel = uipanel(app.PreprocessingTab);
            app.SignalAdjustmentPanel.Title = 'Signal Adjustment';
            app.DetrendBox = uicheckbox(app.SignalAdjustmentPanel);
            app.DetrendBox.Text = '';
            app.DetrendBox.Value = true;
            app.DetrendLabel = uilabel(app.SignalAdjustmentPanel);
            app.DetrendLabel.Text = 'Detrend';
            app.Downsample = uicheckbox(app.SignalAdjustmentPanel);
            app.Downsample.ValueChangedFcn = createCallbackFcn(app, @DownsampleValueChanged, true);
            app.Downsample.Text = '';
            app.Downsample.Value = true;
            app.NormalizeLabel = uilabel(app.SignalAdjustmentPanel);
            app.NormalizeLabel.Text = 'Normalize';
            app.NormalizeBox = uicheckbox(app.SignalAdjustmentPanel);
            app.NormalizeBox.Text = '';
            app.NormalizeBox.Value = true;
            app.DownsampleHzLabel = uilabel(app.SignalAdjustmentPanel);
            app.DownsampleHzLabel.Text = 'Downsample (Hz)';
            app.SamplerateEditFieldLabel = uilabel(app.SignalAdjustmentPanel);
            app.SamplerateEditFieldLabel.Text = ' Sample rate';
            app.SamplerateEditField = uieditfield(app.SignalAdjustmentPanel, 'numeric');
            app.SamplerateEditField.Limits = [0 Inf];
            app.SamplerateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplerateEditFieldValueChanged, true);
            app.SamplerateEditField.Value = 5;
            app.PreprocessingConfigurationLabel = uilabel(app.PreprocessingTab);
            app.PreprocessingConfigurationLabel.Text = 'Preprocessing Configuration';
            app.RunPreprocessingLabel = uilabel(app.PreprocessingTab);
            app.RunPreprocessingLabel.Text = 'Run Preprocessing';
            app.PostprocessingTab = uitab(app.TabGroup);
            app.PostprocessingTab.Title = '   Postprocessing';
            app.FZBandofinterestPanel = uipanel(app.PostprocessingTab);
            app.FZBandofinterestPanel.Title = 'FZ Band of interest';
            app.LowerBoundLabel = uilabel(app.FZBandofinterestPanel);
            app.LowerBoundLabel.Text = 'Lower Bound';
            app.UpperBoundLabel = uilabel(app.FZBandofinterestPanel);
            app.UpperBoundLabel.Text = 'Upper Bound';
            app.LB = uieditfield(app.FZBandofinterestPanel, 'numeric');
            app.LB.Limits = [0 Inf];
            app.LB.ValueChangedFcn = createCallbackFcn(app, @LBValueChanged, true);
            app.LB.Value = 0.001;
            app.UB = uieditfield(app.FZBandofinterestPanel, 'numeric');
            app.UB.Limits = [0 Inf];
            app.UB.ValueChangedFcn = createCallbackFcn(app, @UBValueChanged, true);
            app.UB.Value = 0.15;
            app.ChannelSelectionPanel = uipanel(app.PostprocessingTab);
            app.ChannelSelectionPanel.Title = 'Channel Selection';
            app.LaunchSDGuiButton = uibutton(app.ChannelSelectionPanel, 'state');
            app.LaunchSDGuiButton.ValueChangedFcn = createCallbackFcn(app, @LaunchSDGuiButtonValueChanged, true);
            app.LaunchSDGuiButton.Text = 'Launch SD Gui';
            app.Region1ListBox = uilistbox(app.ChannelSelectionPanel);
            app.Region1ListBox.Items = {};
            app.Region1ListBox.Value = {};
            app.Region2ListBox = uilistbox(app.ChannelSelectionPanel);
            app.Region2ListBox.Items = {};
            app.Region2ListBox.ValueChangedFcn = createCallbackFcn(app, @Region2ListBoxValueChanged, true);
            app.Region2ListBox.Value = {};
            app.EditField = uieditfield(app.ChannelSelectionPanel, 'text');
            app.EditField.Value = 'Region 2';
            app.EditField_2 = uieditfield(app.ChannelSelectionPanel, 'text');
            app.EditField_2.Value = 'Region 1';
            app.HemoglobinParametersPanel_2 = uipanel(app.PostprocessingTab);
            app.HemoglobinParametersPanel_2.Title = 'Hemoglobin Parameters';
            app.CheckallhemosignalsegHbOHbTHbLabel_2 = uilabel(app.HemoglobinParametersPanel_2);
            app.CheckallhemosignalsegHbOHbTHbLabel_2.Text = 'Check all hemo signals eg. HbO,Hb,THb';
            app.HbObox_2 = uicheckbox(app.HemoglobinParametersPanel_2);
            app.HbObox_2.Text = '';
            app.HbObox_2.Value = true;
            app.Hbbox_2 = uicheckbox(app.HemoglobinParametersPanel_2);
            app.Hbbox_2.Text = '';
            app.OxyhemoglobinHbOLabel_2 = uilabel(app.HemoglobinParametersPanel_2);
            app.OxyhemoglobinHbOLabel_2.Text = 'Oxyhemoglobin (HbO)';
            app.DeoxyhemoglobinHbLabel_2 = uilabel(app.HemoglobinParametersPanel_2);
            app.DeoxyhemoglobinHbLabel_2.Text = 'Deoxyhemoglobin (Hb)';
            app.TotalhemoglobinTHbLabel_2 = uilabel(app.HemoglobinParametersPanel_2);
            app.TotalhemoglobinTHbLabel_2.Text = 'Total hemoglobin (THb)';
            app.OxygenSaturationStO2Label_2 = uilabel(app.HemoglobinParametersPanel_2);
            app.OxygenSaturationStO2Label_2.Text = 'Oxygen Saturation (StO2)';
            app.THbbox_2 = uicheckbox(app.HemoglobinParametersPanel_2);
            app.THbbox_2.Text = '';
            app.StO2box_2 = uicheckbox(app.HemoglobinParametersPanel_2);
            app.StO2box_2.Text = '';
            app.Panel_4 = uipanel(app.PostprocessingTab);
            app.SubjectSelectionListBox_4 = uilistbox(app.Panel_4);
            app.SubjectSelectionListBox_4.Items = {};
            app.SubjectSelectionListBox_4.Multiselect = 'on';
            app.SubjectSelectionListBox_4.Value = {};
            app.RunPostprocessingButton = uibutton(app.Panel_4, 'state');
            app.RunPostprocessingButton.ValueChangedFcn = createCallbackFcn(app, @RunPostprocessingButtonValueChanged, true);
            app.RunPostprocessingButton.Text = 'Run Postprocessing';
            app.SelectAllSubjectsCheckBox_4 = uicheckbox(app.Panel_4);
            app.SelectAllSubjectsCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @SelectAllSubjectsCheckBox_4ValueChanged, true);
            app.SelectAllSubjectsCheckBox_4.Text = ' Select All Subjects';
            app.Label_8 = uilabel(app.Panel_4);
            app.Label_8.Text = {'Subjects who have been Initialized and Preprocessed will appear here.'; ''};
            app.PostprocessingConfigurationLabel = uilabel(app.PostprocessingTab);
            app.PostprocessingConfigurationLabel.Text = 'Postprocessing Configuration';
            app.RunPostprocessingLabel = uilabel(app.PostprocessingTab);
            app.RunPostprocessingLabel.Text = 'Run Postprocessing';
            app.FigureSelectionPanel_3 = uipanel(app.PostprocessingTab);
            app.FigureSelectionPanel_3.Title = 'Figure Selection';
            app.CheckBox = uicheckbox(app.FigureSelectionPanel_3);
            app.CheckBox.Text = '';
            app.ChannelWiseStaticCoherencePlotLabel = uilabel(app.FigureSelectionPanel_3);
            app.ChannelWiseStaticCoherencePlotLabel.Text = 'Channel Wise Static Coherence Plot';
            app.WaveletCheckbox = uicheckbox(app.FigureSelectionPanel_3);
            app.WaveletCheckbox.Text = '';
            app.ChannelWiseWavelettransformLabel = uilabel(app.FigureSelectionPanel_3);
            app.ChannelWiseWavelettransformLabel.Text = 'Channel Wise Wavelet transform';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.FigureSelectionPanel_2 = uipanel(app.AnalysisTab);
            app.FigureSelectionPanel_2.Title = 'Figure Selection';
            app.TimeVaryingWaveletTransformPowerLabel = uilabel(app.FigureSelectionPanel_2);
            app.TimeVaryingWaveletTransformPowerLabel.Text = 'Time Varying Wavelet Transform (Power)';
            app.TimevaryingCoherenceLabel = uilabel(app.FigureSelectionPanel_2);
            app.TimevaryingCoherenceLabel.Text = 'Time varying Coherence';
            app.FrequencyVaryingCoherenceByTaskLabel = uilabel(app.FigureSelectionPanel_2);
            app.FrequencyVaryingCoherenceByTaskLabel.Text = 'Frequency Varying Coherence (By Task)';
            app.tvwaveletfigure = uicheckbox(app.FigureSelectionPanel_2);
            app.tvwaveletfigure.Text = '';
            app.tvcohfigure = uicheckbox(app.FigureSelectionPanel_2);
            app.tvcohfigure.Text = '';
            app.fvcohfigure = uicheckbox(app.FigureSelectionPanel_2);
            app.fvcohfigure.Text = '';
            app.TimeVaryingPhaseLabel = uilabel(app.FigureSelectionPanel_2);
            app.TimeVaryingPhaseLabel.Text = 'Time Varying Phase';
            app.tvphasefigure = uicheckbox(app.FigureSelectionPanel_2);
            app.tvphasefigure.Text = '';
            app.AnalysisConfigurationLabel = uilabel(app.AnalysisTab);
            app.AnalysisConfigurationLabel.Text = 'Analysis Configuration';
            app.Panel_5 = uipanel(app.AnalysisTab);
            app.RunAnalysisButton_2 = uibutton(app.Panel_5, 'state');
            app.RunAnalysisButton_2.ValueChangedFcn = createCallbackFcn(app, @RunAnalysisButtonValueChanged, true);
            app.RunAnalysisButton_2.Text = 'Run Analysis';
            app.SubjectSelectionListBox_5 = uilistbox(app.Panel_5);
            app.SubjectSelectionListBox_5.Items = {};
            app.SubjectSelectionListBox_5.Multiselect = 'on';
            app.SubjectSelectionListBox_5.Value = {};
            app.SelectAllSubjectsCheckBox_5 = uicheckbox(app.Panel_5);
            app.SelectAllSubjectsCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @SelectAllSubjectsCheckBox_5ValueChanged, true);
            app.SelectAllSubjectsCheckBox_5.Text = ' Select All Subjects';
            app.SubjectswhohavebeenpostproccessedwillappearhereLabel = uilabel(app.Panel_5);
            app.SubjectswhohavebeenpostproccessedwillappearhereLabel.Text = 'Subjects who have been postproccessed will appear here.';
            app.RunAnalysisLabel = uilabel(app.AnalysisTab);
            app.RunAnalysisLabel.Text = 'Run Analysis';
            app.RunAllTab = uitab(app.TabGroup);
            app.RunAllTab.Title = '   Run All';
            app.RunAllLabel = uilabel(app.RunAllTab);
            app.RunAllLabel.Text = 'Run All';
            app.SubjectSelectionListBox = uilistbox(app.RunAllTab);
            app.SubjectSelectionListBox.Items = {};
            app.SubjectSelectionListBox.Multiselect = 'on';
            app.SubjectSelectionListBox.Value = {};
            app.SelectAllSubjectsCheckBox_6 = uicheckbox(app.RunAllTab);
            app.SelectAllSubjectsCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @SelectAllSub_RunAll, true);
            app.SelectAllSubjectsCheckBox_6.Text = ' Select All Subjects';
            app.RunAllButton = uibutton(app.RunAllTab, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Text = 'Run All';
            app.SubjectswhohavebeeninitializedwillappearhereLabel = uilabel(app.RunAllTab);
            app.SubjectswhohavebeeninitializedwillappearhereLabel.Text = 'Subjects who have been initialized will appear here.';
            % Show the figure after all components are created
            app.EasyNIRSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fNIRSPipeLine

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EasyNIRSUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EasyNIRSUIFigure)
        end
    end
end